classdef Trabalho_vi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImagemcomFiltroLabel        matlab.ui.control.Label
        ImagemOriginalLabel         matlab.ui.control.Label
        EditField2                  matlab.ui.control.NumericEditField
        EditField2Label             matlab.ui.control.Label
        AplicarFiltroButton         matlab.ui.control.Button
        EditField                   matlab.ui.control.NumericEditField
        EditFieldLabel              matlab.ui.control.Label
        SelecioneumaOpoButtonGroup  matlab.ui.container.ButtonGroup
        Opt4Button                  matlab.ui.control.RadioButton
        Opt3Button                  matlab.ui.control.RadioButton
        Opt2Button                  matlab.ui.control.RadioButton
        Opt1Button                  matlab.ui.control.RadioButton
        FiltrosDropDown             matlab.ui.control.DropDown
        FiltrosDropDownLabel        matlab.ui.control.Label
        AbrirImagemButton           matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
         caminhoArq = "";
         nomeImagem = "";
         imgOriginal;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AbrirImagemButton
        function AbrirImagemButtonPushed(app, event)
            %pego o nome da imagem e o caminho pela funcao q abre a janelinha
            [file, path] = uigetfile('*', "Escolha uma Imagem"); 
            app.nomeImagem = file;

            file = strcat (path, file);
            if(file ~= "")
                app.imgOriginal = file;
                app.Image.ImageSource = file;
                %app.Image2.ImageSource = file;
                
            end 
            
        end

        % Selection changed function: SelecioneumaOpoButtonGroup
        function SelecioneumaOpoButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelecioneumaOpoButtonGroup.SelectedObject;

            if (app.FiltrosDropDown.Value == "Passa-Alta")
                switch selectedButton
                    case app.Opt1Button
                        app.EditField.Visible = "on"; 
                        app.EditFieldLabel.Visible = "on";
                        app.EditFieldLabel.Text = "Valor do Kernel";

                    case app.Opt2Button
                        app.EditField.Visible = "on"; 
                        app.EditFieldLabel.Visible = "on";
                        app.EditFieldLabel.Text = "Fator Reforço";
                end
            end

            if (app.FiltrosDropDown.Value == "Passa-Baixa")
                  switch selectedButton 
                    case app.Opt1Button  
                        app.EditField.Visible = "on";
                        app.EditFieldLabel.Visible = "on";
                        app.EditFieldLabel.Text = "Nivel";

                        app.EditField2.Visible = "off";
                        app.EditField2Label.Visible = "off";
                  
                    case app.Opt2Button
                        app.EditField.Visible = "on";
                        app.EditFieldLabel.Visible = "on";
                        app.EditFieldLabel.Text = "Linhas";

                        app.EditField2.Visible = "on";
                        app.EditField2Label.Visible = "on";
                        app.EditField2Label.Text = "Coluna";
                 end
            end

            if (app.FiltrosDropDown.Value == "Ruídos")
                switch selectedButton
                    case app.Opt1Button
                        app.EditField.Visible = "on";
                        app.EditFieldLabel.Visible = "on";
                        app.EditFieldLabel.Text = "Ruido-Speckle";
                        app.EditField2.Visible = "off";
                        app.EditField2Label.Visible = "off";

                    case app.Opt2Button
                        app.EditField.Visible = "on";
                        app.EditFieldLabel.Visible = "on";
                        app.EditFieldLabel.Text = "Valor Médio";
                        app.EditField2.Visible = "on";
                        app.EditField2Label.Visible = "on";
                        app.EditField2Label.Text = "Variancia";

                    case app.Opt3Button
                        app.EditField.Visible = "on";
                        app.EditFieldLabel.Visible = "on";
                        app.EditFieldLabel.Text = "Densidade";
                        app.EditField2.Visible = "off";
                        app.EditField2Label.Visible = "off";
                    case app.Opt4Button
                        app.EditField.Visible = "off";
                        app.EditFieldLabel.Visible = "off";
                        app.EditField2.Visible = "off";
                        app.EditField2Label.Visible = "off";

                end 
            end
        end

        % Value changed function: FiltrosDropDown
        function FiltrosDropDownValueChanged(app, event)
            valor = app.FiltrosDropDown.Value;
            
            app.SelecioneumaOpoButtonGroup.Visible = "off";
            app.EditField.Visible = "off";
            app.EditFieldLabel.Visible = "off";
            app.EditField2.Visible = "off";
            app.EditField2Label.Visible = "off";

             switch valor

                 case "Escala de Cinza"
                     app.EditField.Visible = "off"; 
                     app.EditFieldLabel.Visible = "off";
                     app.EditField2.Visible = "off";
                     app.EditField2Label.Visible = "off";
                     app.SelecioneumaOpoButtonGroup.Visible = "off";

                 case "Threshold"
                     app.EditField.Visible = "on"; 
                     app.EditFieldLabel.Visible = "on";
                     app.EditFieldLabel.Text = "Valor do Limar";
                     app.EditField2.Visible = "off";
                     app.EditField2Label.Visible = "off";
                     app.SelecioneumaOpoButtonGroup.Visible = "off";

                 case "Passa-Alta"
                     app.SelecioneumaOpoButtonGroup.Visible = "on";
                     app.Opt1Button.Visible = "on";
                     app.Opt2Button.Visible = "on";
                     app.Opt3Button.Visible = "off";
                     app.Opt4Button.Visible = "off";

                     app.Opt1Button.Text = "Básico";
                     app.Opt2Button.Text = "Alto Reforço";


                     %%falta variavel

                 case "Passa-Baixa"
                     app.SelecioneumaOpoButtonGroup.Visible = "on";
                     app.Opt1Button.Visible = "on";
                     app.Opt2Button.Visible = "on";
                     app.Opt3Button.Visible = "off";
                     app.Opt4Button.Visible = "off";

                     app.Opt1Button.Text = "Média (Básico)";
                     app.Opt2Button.Text = "Mediana";

                 case "Prewitt"
                     app.SelecioneumaOpoButtonGroup.Visible = "on";
                     app.Opt1Button.Visible = "on";
                     app.Opt1Button.Text = "Horizontal";
                     app.Opt2Button.Visible = "on";
                     app.Opt2Button.Text = "Vertical";
                     app.Opt3Button.Visible = "on";
                     app.Opt3Button.Text = "Horizontal + Vertical";
                     app.Opt4Button.Visible = "on";
                     app.Opt4Button.Text = "Edge";
                     
                 case "Sobel"
                     app.SelecioneumaOpoButtonGroup.Visible = "on";
                     app.Opt1Button.Visible = "on";
                     app.Opt1Button.Text = "Horizontal";
                     app.Opt2Button.Visible = "on";
                     app.Opt2Button.Text = "Vertical";
                     app.Opt3Button.Visible = "on";
                     app.Opt3Button.Text = "Horizontal + Vertical";
                     app.Opt4Button.Visible = "on";
                     app.Opt4Button.Text = "Edge";

                 case "Log"
                     app.EditField.Visible = "on";
                     app.EditFieldLabel.Visible = "on";
                     app.EditField2.Visible = "on";
                     app.EditField2Label.Visible = "on";

                     app.EditFieldLabel.Text = "Sigma";
                     app.EditField2Label.Text = "Tamanho da Matriz";
                   
                 case "Ruídos"
                     app.SelecioneumaOpoButtonGroup.Visible = "on";
                     app.Opt1Button.Visible = "on";
                     app.Opt2Button.Visible = "on";
                     app.Opt3Button.Visible = "on";
                     app.Opt4Button.Visible = "on";
    
                     app.Opt1Button.Text = "Speckle";
                     app.Opt2Button.Text = "Gaussiano";
                     app.Opt3Button.Text = "Salt & Papper";
                     app.Opt4Button.Text = "Poissen";

                 case "Histograma"
                     app.SelecioneumaOpoButtonGroup.Visible = "on";
                     app.Opt1Button.Visible = "on";
                     app.Opt2Button.Visible = "on";
                     app.Opt3Button.Visible = "on";
                     app.Opt4Button.Visible = "on";

                     app.Opt1Button.Text = "Escala Cinza";
                     app.Opt2Button.Text = "RGB";
                     app.Opt3Button.Text = "Equalizado";
                     app.Opt4Button.Text = "Equalizado Adaptativo";

                 case "Contagem Simples"
                     app.EditField.Visible = "on"; 
                     app.EditFieldLabel.Visible = "on";
                     app.EditFieldLabel.Text = "Valor do Limar";
                     app.EditField2.Visible = "off";
                     app.EditField2Label.Visible = "off";
                     app.SelecioneumaOpoButtonGroup.Visible = "off";
                     
             end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            %valor = app.EditField.Value;
            
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
            valor  = app.EditField.Value;
            disp(valor);

            if app.imgOriginal ~= ""
                img = imread(app.imgOriginal);
                disp(app.FiltrosDropDown.Value);

                switch app.FiltrosDropDown.Value
                    case "Escala de Cinza"
                        try
                            imgGray = im2gray(img);
                            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                            nomeArq = "Escala_Cinza" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                            nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                
                            % Salvar a imagem binarizada
                            imwrite(imgGray, nomeCaminho);
                            app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                        catch ME
                            disp('Erro ao aplicar filtro:');
                            disp(ME.message);
                        end

                    case "Threshold"
                        if valor > 0
                            try
                                % Converter para escala de cinza se necessário
                                if size(img, 3) == 3
                                    img = rgb2gray(img);
                                end
                                imgBi = imbinarize(img, valor);
                                %imshow(imgBi);

                                % Gerar um timestamp para garantir nomes de arquivos únicos
                                timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                nomeArq = "Threshold" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                
                                % Salvar a imagem binarizada
                                imwrite(imgBi, nomeCaminho);
                                app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                           catch ME
                               disp('Erro ao aplicar filtro:');
                               disp(ME.message);
                            end
                        end

                    case "Passa-Alta"
                        % Converter para escala de cinza se necessário
                        if size(img, 3) == 3
                            img = rgb2gray(img);
                        end
                        selecaoOpt = app.SelecioneumaOpoButtonGroup.SelectedObject;
                        switch selecaoOpt
                            case app.Opt1Button % Passa Alta - Basica
                                try
                                    kernelPA = [-1, -1, -1; -1, app.EditField.Value, -1; -1, -1, -1];
                                    PA_basica = imfilter(img, kernelPA, 'replicate');
                                    
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Passa_Alta_Basica" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                
                                    % Salvar a imagem Passa-Alta
                                    imwrite(PA_basica, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end
                            case app.Opt2Button % Passa Alta - Alto Reforço
                                if valor > 1
                                    try
                                        img = imread(app.imgOriginal);
                                        h = fspecial('gaussian', [5, 5], 2);
                                        imgSuavizada = imfilter(img, h, 'replicate');
                                        % Calcular o filtro passa-alta
                                        imgPassaAlta = img - imgSuavizada;

                                        % Aplicar o filtro de alto reforço
                                        imgAltoReforco = valor * img + imgPassaAlta;

                                        % Gerar um timestamp para garantir nomes de arquivos únicos
                                        timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                        nomeArq = "Passa_Alta_Basica" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                        nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                    
                                        % Salvar a imagem Passa-Alta
                                        imwrite(imgAltoReforco, nomeCaminho);
                                        app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                    catch ME
                                        disp('Erro ao aplicar filtro:');
                                        disp(ME.message);
                                    end
                                end
                        end
                    case "Passa-Baixa"
                        selecioneOp = app.SelecioneumaOpoButtonGroup.SelectedObject;

                        switch selecioneOp
                            case app.Opt1Button % Passa Baixa Media 
                                if valor > 0
                                    try
                                        if size(img, 3) == 3
                                            img = rgb2gray(img);
                                        end
                                        Media = fspecial('average', valor);
                                        imgSuavisada = imfilter(img, Media, 'replicate');

                                        % Gerar um timestamp para garantir nomes de arquivos únicos
                                        timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                        nomeArq = "Passa_Baixa_Media" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                        nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                    
                                        % Salvar a imagem Passa-Alta
                                        imwrite(imgSuavisada, nomeCaminho);
                                        app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                                    catch ME
                                        disp('Erro ao aplicar filtro:');
                                        disp(ME.message);
                                    end
                                end
                            case app.Opt2Button % Passa Baixa Mediana
                                try
                                    linha1 = app.EditField.Value;
                                    linha2 = app.EditField2.Value;
                                    
                                    if size(img, 3) == 3
                                       img = rgb2gray(img);
                                    end

                                    tamanhoJanela = [linha1, linha2];
                                    imgFiltrada = medfilt2(img,tamanhoJanela);
                                    %faze um if se a imagem eh colorida ou
                                    %nao
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Passa_Baixa_Mediana" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                    
                                    % Salvar a imagem Passa-Baixa
                                    imwrite(imgFiltrada, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end
                        end
                    
                    case "Roberts"
                        try
                            if size(img, 3) == 3
                                img = rgb2gray(img);
                            end
                            robertsEdg = edge(img, "roberts");
                            % Gerar um timestamp para garantir nomes de arquivos únicos
                            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                            nomeArq = "Roberts" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                            nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                        
                            % Salvar a imagem 
                            imwrite(robertsEdg, nomeCaminho);
                            app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                        catch ME
                            disp('Erro ao aplicar filtro:');
                            disp(ME.message);
                        end

                    case "Prewitt"
                        if size(img, 3) == 3
                            img = rgb2gray(img);
                        end
                        h = fspecial('prewitt');
                        selecioneOp = app.SelecioneumaOpoButtonGroup.SelectedObject;

                        switch selecioneOp
                            case app.Opt1Button %Prewitt Horizontal
                                try
                                    preH = imfilter(img, h);

                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Prewitt_Horizontal" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                                    % Salvar a imagem 
                                    imwrite(preH, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt2Button %Prewitt Vertical
                                try
                                    preV = imfilter(img, h');

                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Prewitt_Vertical" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                                    % Salvar a imagem 
                                    imwrite(preV, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt3Button %Prewitt Horizontal-Vertical
                                try
                                    preh = imfilter(img, h);
                                    prev = imfilter(img, h');
                                    prehv = ph + pv;
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Prewitt_HorVer" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                                    % Salvar a imagem 
                                    imwrite(prehv, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt4Button % Prewitt Edge
                                try
                                    preEdge = edge(img, "prewitt");
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Prewitt_Edge" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                                    % Salvar a imagem 
                                    imwrite(preEdge, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end
                        end

                    case "Sobel"
                        if size(img, 3) == 3
                            img = rgb2gray(img);
                        end
                        h = fspecial('sobel');
                        selecioneOp = app.SelecioneumaOpoButtonGroup.SelectedObject;

                        switch selecioneOp
                            case app.Opt1Button % Sobel Horizontal
                                try 
                                    sobelH = imfilter(img, h);
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Sobel_Horizontal" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                                    % Salvar a imagem 
                                    imwrite(sobelH, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt2Button % Sobel Vertical
                                try 
                                    sobelV = imfilter(img, h');
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Sobel_Vertical" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                                    % Salvar a imagem 
                                    imwrite(sobelV, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt3Button % Sobel Horizontal-Vertical
                                try 
                                    sobelH = imfilter(img, h);
                                    sobelV = imfilter(img, h');
                                    sobelHV = sobelH + sobelV;
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Sobel_HorVer" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                                    % Salvar a imagem
                                    imwrite(sobelHV, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt4Button % Sobel Edge
                                try 
                                    sobelEdge = edge(img, "sobel"); 
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Sobel_Edge" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                                    % Salvar a imagem
                                    imwrite(sobelEdge, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end
                               
                        end

                    case "Log"
                        if size(img, 3) == 3
                            img = rgb2gray(img);
                        end
                        try
                            sigma = app.EditField.Value;
                            tamanhoMatriz = app.EditField2.Value;                               
                            parametroImg = fspecial('log', [tamanhoMatriz , tamanhoMatriz], sigma);  
                            imgFiltro = imfilter(img, parametroImg);

                            % Gerar um timestamp para garantir nomes de arquivos únicos
                            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                            nomeArq = "Log" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                            nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                            % Salvar a imagem 
                            imwrite(imgFiltro, nomeCaminho);
                            app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                        catch ME
                            disp('Erro ao aplicar filtro:');
                            disp(ME.message);
                        end

                    case "Zerocross"
                        if size(img, 3) == 3
                            img = rgb2gray(img);
                        end
                        try
                            aplicacao1 = fspecial('laplacian');
                            imgFiltro = edge(img, 'zerocross', aplicacao1);
                            % Gerar um timestamp para garantir nomes de arquivos únicos
                            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                            nomeArq = "Zerocross" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                            nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                            % Salvar a imagem 
                            imwrite(imgFiltro, nomeCaminho);
                            app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                        catch ME
                            disp('Erro ao aplicar filtro:');
                            disp(ME.message);
                        end

                    case "Canny"
                        if size(img, 3) == 3
                            img = rgb2gray(img);
                        end
                        try 
                            imgFiltro = edge(img, 'canny');
                            % Gerar um timestamp para garantir nomes de arquivos únicos
                            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                            nomeArq = "Zerocross" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                            nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                
                            % Salvar a imagem 
                            imwrite(imgFiltro, nomeCaminho);
                            app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                        catch ME
                            disp('Erro ao aplicar filtro:');
                            disp(ME.message);
                        end

                    case "Ruídos"
                        selecioneOp = app.SelecioneumaOpoButtonGroup.SelectedObject;

                        switch selecioneOp
                            case app.Opt1Button
                                try
                                    ruidoSpeckler = app.EditField.Value;
                                    imgSpeckle = imnoise(img, 'speckle', ruidoSpeckler);
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Ruído_Speckle" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                        
                                    % Salvar a imagem 
                                    imwrite(imgSpeckle, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt2Button
                                try
                                    valorMedio = app.EditField.Value;
                                    varianciaRuido = app.EditField2.Value;
                                    imgGaussiana = imnoise(img, 'gaussian', valorMedio, varianciaRuido);
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Ruído_Gaussian" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                        
                                    % Salvar a imagem 
                                    imwrite(imgGaussiana, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt3Button
                                try
                                    densidade = app.EditField.Value;
                                    imgSaltAndPepper = imnoise(img, 'salt & pepper', densidade);
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Ruído_SaltAndPepper" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                        
                                    % Salvar a imagem 
                                    imwrite(imgSaltAndPepper, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt4Button
                                try
                                    imgPoisson = imnoise(img, 'poisson');
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "Ruído_Poisson" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                        
                                    % Salvar a imagem 
                                    imwrite(imgPoisson, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end
                        end
                    
                    case "Watershed"
                        try
                            if size(img, 3) == 3
                                img = rgb2gray(img);
                            end
                            binImg = imbinarize(img); % Binarizar a imagem
                            
                            % Complemento da imagem (invertendo o foreground e background)
                            binImg = imcomplement(binImg);
                            
                            % Aplicar a Transformada da Distância
                            distTransform = bwdist(binImg);
                            
                            % Inversão da Transformada da Distância (necessária para Watershed)
                            distTransform = -distTransform;
                            
                            % Marcar os mínimos da transformada da distância como marcadores iniciais
                            mask = imextendedmin(distTransform, 2);
                            
                            % Modificar a transformada para impedir a divisão dos objetos
                            distTransform = imimposemin(distTransform, mask);
                            
                            % Aplicar o Watershed
                            watershedLabels = watershed(distTransform);
                            
                            % Criar uma imagem de borda a partir da segmentação Watershed
                            watershedBorders = watershedLabels == 0;
                            
                            % Superpor as bordas da segmentação na imagem original
                            segmentedImg = img;
                            segmentedImg(watershedBorders) = 255;
                            % Gerar um timestamp para garantir nomes de arquivos únicos
                            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                            nomeArq = "Watersheld" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                            nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                            
                            % Salvar a imagem 
                            imwrite(segmentedImg, nomeCaminho);
                            app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app   
                        catch ME
                            disp('Erro ao aplicar filtro:');
                            disp(ME.message);
                        end

                    case "Histograma"
                        selecioneOp = app.SelecioneumaOpoButtonGroup.SelectedObject;
                        switch selecioneOp
                            case app.Opt1Button % Histograma da Escala de Cinza
                                try
                                    if size(img, 3) == 3
                                        img = rgb2gray(img);
                                    end
                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "HistogramaEC" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                                    
                                    % Salvar a imagem 
                                    imwrite(img, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                                    imhist(img);
                                    title('Histograma de Escala de Cinza');

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt2Button % Histograma RGB % tem que arrumar
                                try
                                    % Verifica se a imagem é RGB
                                    if size(img, 3) == 3
                                        % Extrai os canais de cor
                                        redChannel = img(:,:,1);
                                        greenChannel = img(:,:,2);
                                        blueChannel = img(:,:,3);
                            
                                        % Gerar um timestamp para garantir nomes de arquivos únicos
                                        timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                        nomeArq = "HistogramaRGB_" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                        nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                                
                                        % Salvar a imagem 
                                        imwrite(img, nomeCaminho);
                                        app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                            
                                        % Plotar os histogramas dos canais
                                        figure;
                                        subplot(3,1,1);
                                        imhist(redChannel);
                                        title('Histograma do Canal Vermelho');
                                        
                                        subplot(3,1,2);
                                        imhist(greenChannel);
                                        title('Histograma do Canal Verde');
                                        
                                        subplot(3,1,3);
                                        imhist(blueChannel);
                                        title('Histograma do Canal Azul');
                                    else
                                        error('A imagem não é RGB. Não é possível gerar o histograma RGB.');
                                    end
                                
                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt3Button % Histograma Equalizado TEM QUE ARRUMAR
                                try
                                    if size(img, 3) == 3
                                        img = rgb2gray(img);
                                    end
                                    imgEqualizada = histeq(img); % Equalizar o histograma

                                    % Gerar um timestamp para garantir nomes de arquivos únicos
                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "HistogramaEC" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                                    
                                    % Salvar a imagem 
                                    imwrite(imgEqualizada, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
            
                                    imhist(imgEqualizada);
                                    title('Histograma Equalizado');

                                catch ME
                                    disp('Erro ao aplicar filtro:');
                                    disp(ME.message);
                                end

                            case app.Opt4Button
                                try
                                    if size(img, 3) == 3
                                        img = rgb2gray(img);
                                    end
                                    imgCLAHE = adapthisteq(img); % Aplicar CLAHE

                                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                                    nomeArq = "HistogramaEC" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                                    nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                                                                    
                                    % Salvar a imagem 
                                    imwrite(imgCLAHE, nomeCaminho);
                                    app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app
                                    imhist(imgCLAHE);
                                    title('Histograma CLAHE');

                                catch ME
                                    disp('Erro ao aplicar filtro:'); 
                                    disp(ME.message);
                                end
                        end
                    case "Contagem Simples"
                        valor = app.EditField.Value;
                        try
                            if size(img, 3) == 3
                               img = rgb2gray(img);
                            end
                            imgBin = img > valor;
                            
                            % Identificar e contar os objetos
                            [labels, numObjects] = bwlabel(imgBin);
                            hold on;

                            % Marcar e contar os objetos
                            for i = 1:numObjects
                                % Obter as coordenadas dos pixels do objeto
                                [rows, cols] = find(labels == i);
                                
                                % Desenhar um contorno ao redor do objeto
                                rectangle('Position', [min(cols), min(rows), max(cols)-min(cols), max(rows)-min(rows)], ...
                                          'EdgeColor', 'r', 'LineWidth', 2);
                                
                                % Colocar um número sobre o objeto
                                text(mean(cols), mean(rows), num2str(i), 'Color', 'yellow', 'FontSize', 12);
                            end
                            
                            hold off;
                            title(['Número de objetos: ', num2str(numObjects)]);

                            % Gerar um timestamp para garantir nomes de arquivos únicos
                            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                            nomeArq = "ContagemSimples_" + timestamp + "_" + app.nomeImagem + ".png"; % Nome do arquivo inclui timestamp
                            nomeCaminho = fullfile(app.caminhoArq, nomeArq); % Caminho da imagem
                            
                            % Salvar a imagem 
                            imwrite(imgBin, nomeCaminho);
                            app.Image2.ImageSource = nomeCaminho; % Atualiza a exibição da imagem no app

                        catch ME
                            disp('Erro ao aplicar filtro:'); 
                            disp(ME.message);
                        end
                end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.AbrirImagemButton = uibutton(app.UIFigure, 'push');
            app.AbrirImagemButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirImagemButtonPushed, true);
            app.AbrirImagemButton.Text = 'Abrir Imagem';
            app.FiltrosDropDownLabel = uilabel(app.UIFigure);
            app.FiltrosDropDownLabel.Text = 'Filtros';
            app.FiltrosDropDown = uidropdown(app.UIFigure);
            app.FiltrosDropDown.Items = {'Escala de Cinza', 'Threshold', 'Passa-Alta', 'Passa-Baixa', 'Roberts', 'Prewitt', 'Sobel', 'Log', 'Zerocross', 'Canny', 'Ruídos', 'Watershed', 'Histograma', 'Contagem Simples'};
            app.FiltrosDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltrosDropDownValueChanged, true);
            app.FiltrosDropDown.Value = 'Escala de Cinza';
            app.SelecioneumaOpoButtonGroup = uibuttongroup(app.UIFigure);
            app.SelecioneumaOpoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelecioneumaOpoButtonGroupSelectionChanged, true);
            app.SelecioneumaOpoButtonGroup.Title = 'Selecione uma Opção';
            app.SelecioneumaOpoButtonGroup.Visible = 'off';
            app.Opt1Button = uiradiobutton(app.SelecioneumaOpoButtonGroup);
            app.Opt1Button.Text = 'Opt1';
            app.Opt1Button.Value = true;
            app.Opt2Button = uiradiobutton(app.SelecioneumaOpoButtonGroup);
            app.Opt2Button.Text = 'Opt2';
            app.Opt3Button = uiradiobutton(app.SelecioneumaOpoButtonGroup);
            app.Opt3Button.Text = 'Opt3';
            app.Opt4Button = uiradiobutton(app.SelecioneumaOpoButtonGroup);
            app.Opt4Button.Text = 'Opt4';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Visible = 'off';
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Visible = 'off';
            app.AplicarFiltroButton = uibutton(app.UIFigure, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar Filtro';
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Visible = 'off';
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Visible = 'off';
            app.ImagemOriginalLabel = uilabel(app.UIFigure);
            app.ImagemOriginalLabel.Text = 'Imagem Original';
            app.ImagemcomFiltroLabel = uilabel(app.UIFigure);
            app.ImagemcomFiltroLabel.Text = 'Imagem com Filtro';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trabalho_vi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end