classdef trabalho < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        AplicarFiltroButton          matlab.ui.control.Button
        SigmaEditField               matlab.ui.control.NumericEditField
        SigmaEditFieldLabel          matlab.ui.control.Label
        FatordeReforoEditField       matlab.ui.control.NumericEditField
        FatordeReforoEditFieldLabel  matlab.ui.control.Label
        ParametrosLabel              matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        FiltrosDropDown              matlab.ui.control.DropDown
        FiltrosDropDownLabel         matlab.ui.control.Label
        AbririmagemButton            matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        img % Variable to store the loaded image
        ax % Variable to store the axes
        thresholdValor = 180; % Description
        fatorReforco = 1.5; % Description
        sigmaValor = 3; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AbririmagemButton
        function AbririmagemButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
             if isequal(file, 0)
                disp('User selected Cancel');
             else
               % Read and display the image
                app.img = imread(fullfile(path, file));
                % If RightPanel is a uipanel, create an axes inside it
                app.ax = uiaxes('Parent', app.RightPanel);
    
                % Display the image
                imshow(app.img, 'Parent', app.ax, 'InitialMagnification', 'fit');

                % Ajuste as propriedades do eixo para centralizar e ampliar a imagem
                app.ax.Units = 'normalized'; % Use unidades normalizadas para posicionamento
                app.ax.Position = [0 0 1 1]; % Faça o eixo ocupar todo o painel
                app.ax.XTick = []; % Remova as marcas dos eixos x
                app.ax.YTick = []; % Remova as marcas dos eixos y
                app.ax.Box = 'on'; % Adicione uma borda ao redor do eixo, opcional
                %title(ax, 'Imagem Aberta');
            end
        end

        % Value changed function: FiltrosDropDown
        function FiltrosDropDownValueChanged(app, event)
            selectedFilter = app.FiltrosDropDown.Value;
            if isempty(app.img)
                return;
            end

            switch selectedFilter
                case ''
                    imshow(app.img, 'Parent', app.ax, 'InitialMagnification', 'fit');
                case 'Threshold'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end

                    %if isempty(app.thresholdValor)
                    %app.thresholdValue = 128; % Valor padrão se não for definido
                    %end
                    % Aplicar o threshold
                    imgThreshold = imgGray > app.thresholdValor;
                    imshow(imgThreshold, 'Parent', app.ax, 'InitialMagnification', 'fit');
                
                case 'Escala de Cinza'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    imshow(imgGray, 'Parent', app.ax, 'InitialMagnification', 'fit');
                
                case 'Passa-Alta: Básico'
                     if size(app.img, 3) == 3
                        myimage = rgb2gray(app.img);
                     else 
                        myimage = app.img;
                     end
                     f = fspecial('laplacian');
                     lapedg = imfilter(myimage, f, "symmetric");
                     imshow(uint8(lapedg), 'Parent', app.ax, 'InitialMagnification', 'fit');
                
                case 'Passa-Alta: Alto Reforço'
                     % Definir o filtro Laplaciano
                      laplacianFilter = [0 -1 0; -1 4 -1; 0 -1 0];
    
                    % Converter a imagem para escala de cinza                  
                      if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                      else 
                        imgGray = app.img;
                     end
                      
                    
                    % Aplicar o filtro Laplaciano
                    imgLaplacian = imfilter(double(imgGray), laplacianFilter, 'replicate');
    
                    % Adicionar a imagem original ao resultado filtrado
                    imgFiltrado = double(imgGray) + app.fatorReforco * imgLaplacian;
                    
                    % Normalizar a imagem para o intervalo [0, 255]
                    imgFiltrado = mat2gray(imgFiltrado) * 255;
                    imshow(uint8(imgFiltrado), 'Parent', app.ax, 'InitialMagnification', 'fit');
                
                case 'Passa-Baixa: Media'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    % Criar filtro de média
                    h = fspecial('average', [5 5]);
                    imgFiltered = imfilter(imgGray, h, 'replicate');
                    imshow(uint8(imgFiltered), 'Parent', app.ax, 'InitialMagnification', 'fit');

                case 'Passa-Baixa: Mediana'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    % Aplicar filtro de mediana
                    imgFiltered = medfilt2(imgGray, [5 5]);
                    imshow(uint8(imgFiltered), 'Parent', app.ax, 'InitialMagnification', 'fit');

                case 'Roberts'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    %subplot(3,3,1);
                    % Operadores Roberts
                    robertsedg = edge(imgGray, 'roberts');
                    %subplot(3,3,5);
                    imshow(robertsedg, 'Parent', app.ax, 'InitialMagnification', 'fit');

                case 'Prewitt'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    prewittsedg = edge(imgGray, 'prewitt');
                    imshow(prewittsedg, 'Parent', app.ax, 'InitialMagnification', 'fit');
                
                case 'Sobel: Vertical'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    sobelvrt = edge(imgGray, 'sobel' , 'vertical');
                    imshow(sobelvrt, 'Parent', app.ax, 'InitialMagnification', 'fit');

                case 'Sobel: Horizontal'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    sobelhz = edge(imgGray, 'sobel', 'horizontal');
                    imshow(sobelhz, 'Parent', app.ax, 'InitialMagnification', 'fit');
                    
                case 'Sobel: All'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    sobelall = edge(imgGray, 'sobel', 'both');
                    imshow(sobelall, 'Parent', app.ax, 'InitialMagnification', 'fit');
                
                case 'Log'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    f = fspecial('log', [15, 15], app.sigmaValor);
                    logedg1 = edge(imgGray, 'zerocross', [], f);
                    imshow(logedg1, 'Parent', app.ax, 'InitialMagnification', 'fit');
               
                case 'Canny'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    cannyedg = edge(imgGray, 'canny');
                    imshow(cannyedg, 'Parent', app.ax, 'InitialMagnification', 'fit');
                case 'Zerocross'
                    if size(app.img, 3) == 3
                        imgGray = rgb2gray(app.img);
                    else 
                        imgGray = app.img;
                    end
                    % Definir filtro Laplaciano da Gaussiana (LoG) para suavizar antes de aplicar zerocross
                    f = fspecial('log', [15, 15], app.sigmaValor);
                    imgFiltered = imfilter(imgGray, f, 'replicate');
                    % Aplicar zerocross para encontrar bordas
                    zerocrossEdges = edge(imgFiltered, 'zerocross');
                    imshow(zerocrossEdges, 'Parent', app.ax, 'InitialMagnification', 'fit');
            
            
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            app.thresholdValor = app.ThresholdEditField.Value;
            % Reaplica o filtro atual com o novo valor de threshold
            if ~isempty(app.img)
                % Verifique o filtro selecionado
                selectedFilter = app.FiltrosDropDown.Value;
            
                % Reaplica o filtro com o novo threshold
                switch selectedFilter
                    case 'Threshold'
                        if size(app.img, 3) == 3
                            imgGray = rgb2gray(app.img);
                        else 
                            imgGray = app.img;
                        end
                        % Aplicar o threshold
                        imgThreshold = imgGray > app.thresholdValor;
                    
                        % Exibir a imagem com o novo threshold
                        imshow(imgThreshold, 'Parent', app.ax, 'InitialMagnification', 'fit');
                     end
            end
        end

        % Value changed function: FatordeReforoEditField
        function FatordeReforoEditFieldValueChanged(app, event)
            app.fatorReforco = app.FatordeReforoEditField.Value;
             if ~isempty(app.img)
                % Verifique o filtro selecionado
                selectedFilter = app.FiltrosDropDown.Value;
            
                % Reaplica o filtro com o novo threshold
                switch selectedFilter
                    case 'Passa-Alta: Alto Reforço'
                        % Definir o filtro Laplaciano
                        laplacianFilter = [0 -1 0; -1 4 -1; 0 -1 0];
    
                       % Converter a imagem para escala de cinza                  
                       if size(app.img, 3) == 3
                            imgGray = rgb2gray(app.img);
                       else 
                            imgGray = app.img;
                       end

                      % Aplicar o filtro Laplaciano
                      imgLaplacian = imfilter(double(imgGray), laplacianFilter, 'replicate');
    
                      % Adicionar a imagem original ao resultado filtrado
                      imgFiltrado = double(imgGray) + app.fatorReforco * imgLaplacian;
                    
                      % Normalizar a imagem para o intervalo [0, 255]
                      imgFiltrado = mat2gray(imgFiltrado) * 255;
                      imshow(uint8(imgFiltrado), 'Parent', app.ax, 'InitialMagnification', 'fit');

                end
            end
        end

        % Value changed function: SigmaEditField
        function SigmaEditFieldValueChanged(app, event)
            app.sigmaValor = app.SigmaEditField.Value;

            if ~isempty(app.img)
                % Verifique o filtro selecionado
                selectedFilter = app.FiltrosDropDown.Value;
            
                % Reaplica o filtro com o novo threshold
                switch selectedFilter
                    case 'Log'
                        if size(app.img, 3) == 3
                            imgGray = rgb2gray(app.img);
                        else 
                            imgGray = app.img;
                        end
                        f = fspecial('log', [15, 15], app.sigmaValor);
                        logedg1 = edge(imgGray, 'zerocross', [], f);
                        imshow(logedg1, 'Parent', app.ax, 'InitialMagnification', 'fit');      
                end
            end
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AbririmagemButton = uibutton(app.LeftPanel, 'push');
            app.AbririmagemButton.ButtonPushedFcn = createCallbackFcn(app, @AbririmagemButtonPushed, true);
            app.AbririmagemButton.Text = 'Abrir imagem ';
            app.FiltrosDropDownLabel = uilabel(app.LeftPanel);
            app.FiltrosDropDownLabel.Text = 'Filtros';
            app.FiltrosDropDown = uidropdown(app.LeftPanel);
            app.FiltrosDropDown.Items = {'', 'Threshold', 'Escala de Cinza', 'Passa-Alta: Básico', 'Passa-Alta: Alto Reforço', 'Passa-Baixa: Media', 'Passa-Baixa: Mediana', 'Roberts', 'Prewitt', 'Sobel: Vertical', 'Sobel: Horizontol', 'Sobel: All', 'Log', 'Zerocross', 'Canny', 'Ruídos', 'Watersheld ', 'Histograma', 'Ajuste adaptativo de histograma', 'Contagem simples de objetos limiarizados com marcação na imagem'};
            app.FiltrosDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltrosDropDownValueChanged, true);
            app.FiltrosDropDown.Value = 'Threshold';
            app.ThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ParametrosLabel = uilabel(app.LeftPanel);
            app.ParametrosLabel.Text = 'Parametros';
            app.FatordeReforoEditFieldLabel = uilabel(app.LeftPanel);
            app.FatordeReforoEditFieldLabel.Text = 'Fator de Reforço';
            app.FatordeReforoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FatordeReforoEditField.ValueChangedFcn = createCallbackFcn(app, @FatordeReforoEditFieldValueChanged, true);
            app.SigmaEditFieldLabel = uilabel(app.LeftPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaEditFieldValueChanged, true);
            app.AplicarFiltroButton = uibutton(app.LeftPanel, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar Filtro';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Tooltip = {''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trabalho

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end